classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        EdgeRespLabel      matlab.ui.control.Label
        OldEdgeRespLabel   matlab.ui.control.Label
        PEditFieldLabel    matlab.ui.control.Label
        PEditField         matlab.ui.control.NumericEditField
        PEditField_2Label  matlab.ui.control.Label
        PEditField_2       matlab.ui.control.NumericEditField
        NEditFieldLabel    matlab.ui.control.Label
        NEditField         matlab.ui.control.NumericEditField
        NEditField_2Label  matlab.ui.control.Label
        NEditField_2       matlab.ui.control.NumericEditField
        PlottingareaLabel  matlab.ui.control.Label
        original           matlab.ui.control.UIAxes
        filtered           matlab.ui.control.UIAxes
        OERButton          matlab.ui.control.Button
        ERButton           matlab.ui.control.Button
        INITButton         matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: ERButton
        function ERButtonPushed(app, event)
            load im;
            im = edgeresponse(im);
            imshow(im, 'parent', app.filtered);
            save('im', 'im');
        end

        % Button pushed function: INITButton
        function INITButtonPushed(app, event)
            im = imread('imtest.JPG');
            im = im2double(rgb2gray(im));
            imshow(im, 'parent', app.original);
            im = imcomplement(im);
            save('im', 'im');
        end

        % Button pushed function: OERButton
        function OERButtonPushed(app, event)
            load im;
            im = oldedgeresponse(im);
            imshow(im, 'parent', app.filtered);
            save('im', 'im');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.EdgeRespLabel = uilabel(app.UIFigure);
            app.EdgeRespLabel.Text = 'Edge Resp.';
            app.OldEdgeRespLabel = uilabel(app.UIFigure);
            app.OldEdgeRespLabel.Text = 'Old Edge Resp.';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField_2Label = uilabel(app.UIFigure);
            app.PEditField_2Label.Text = 'P';
            app.PEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField_2Label = uilabel(app.UIFigure);
            app.NEditField_2Label.Text = 'N';
            app.NEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PlottingareaLabel = uilabel(app.UIFigure);
            app.PlottingareaLabel.Text = 'Plotting area';
            app.original = uiaxes(app.UIFigure);
            title(app.original, 'Title');
            xlabel(app.original, 'X');
            ylabel(app.original, 'Y');
            app.filtered = uiaxes(app.UIFigure);
            title(app.filtered, 'Title');
            xlabel(app.filtered, 'X');
            ylabel(app.filtered, 'Y');
            app.OERButton = uibutton(app.UIFigure, 'push');
            app.OERButton.ButtonPushedFcn = createCallbackFcn(app, @OERButtonPushed, true);
            app.OERButton.Text = 'OER';
            app.ERButton = uibutton(app.UIFigure, 'push');
            app.ERButton.ButtonPushedFcn = createCallbackFcn(app, @ERButtonPushed, true);
            app.ERButton.Text = 'ER';
            app.INITButton = uibutton(app.UIFigure, 'push');
            app.INITButton.ButtonPushedFcn = createCallbackFcn(app, @INITButtonPushed, true);
            app.INITButton.Text = 'INIT';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end